classdef SpectrumAnalyzerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        UploadTab                      matlab.ui.container.Tab
        MATLABSPECTRUMANALYZERLabel    matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        UploadAudioButton              matlab.ui.control.Button
        filepathLabel                  matlab.ui.control.EditField
        AnalyzeTab                     matlab.ui.container.Tab
        TimeDom                        matlab.ui.control.UIAxes
        FreqDom                        matlab.ui.control.UIAxes
        WindowTypeDropDownLabel        matlab.ui.control.Label
        WindType                       matlab.ui.control.DropDown
        WindowSettingsLabel            matlab.ui.control.Label
        NDFTDropDownLabel              matlab.ui.control.Label
        NDFTDropDown                   matlab.ui.control.DropDown
        DFTSettingLabel                matlab.ui.control.Label
        ApplyButton                    matlab.ui.control.Button
        WindowLengthEditFieldLabel_2   matlab.ui.control.Label
        WindLength                     matlab.ui.control.NumericEditField
        CalculationsTab                matlab.ui.container.Tab
        RMSgraph                       matlab.ui.control.UIAxes
        StartfreqLabel                 matlab.ui.control.Label
        startFreq                      matlab.ui.control.NumericEditField
        EndfreqLabel                   matlab.ui.control.Label
        EndFreq                        matlab.ui.control.NumericEditField
        PowerEditFieldLabel            matlab.ui.control.Label
        PowerDisplay                   matlab.ui.control.NumericEditField
        POWERCALCULATORLabel           matlab.ui.control.Label
        NumberOfSegmentsDropDownLabel  matlab.ui.control.Label
        NumSegMenu                     matlab.ui.control.DropDown
        LengthOfSegmentsDropDownLabel  matlab.ui.control.Label
        LenSegMenu                     matlab.ui.control.DropDown
        DivisiontoSegmentsLabel        matlab.ui.control.Label
        ApplyButton_2                  matlab.ui.control.Button
        ComparisonTab                  matlab.ui.container.Tab
        TimeDom_3                      matlab.ui.control.UIAxes
        FreqDom_3                      matlab.ui.control.UIAxes
        TimeDom_4                      matlab.ui.control.UIAxes
        FreqDom_4                      matlab.ui.control.UIAxes
        WindowSettingsLabel_2          matlab.ui.control.Label
        DFTSettingLabel_2              matlab.ui.control.Label
        ApplyButton_3                  matlab.ui.control.Button
        WindowTypeDropDownLabel_2      matlab.ui.control.Label
        WindType_2                     matlab.ui.control.DropDown
        NDFTDropDown_2Label            matlab.ui.control.Label
        NDFTDropDown_2                 matlab.ui.control.DropDown
        WindowLengthEditFieldLabel_3   matlab.ui.control.Label
        WindLength_2                   matlab.ui.control.NumericEditField
        WindowSettingsLabel_3          matlab.ui.control.Label
        WindowTypeDropDownLabel_3      matlab.ui.control.Label
        WindType_3                     matlab.ui.control.DropDown
        WindowLengthEditFieldLabel_4   matlab.ui.control.Label
        WindLength_3                   matlab.ui.control.NumericEditField
        DFTSettingLabel_3              matlab.ui.control.Label
        NDFTDropDown_3Label            matlab.ui.control.Label
        NDFTDropDown_3                 matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        audioFile;
        x; 
        X;
        fs;
        n;
        xWindowed;
        numValidSplits;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.WindLength.Value = 0;
        end

        % Button pushed function: UploadAudioButton
        function UploadAudioButtonPushed(app, event)
            [file,path] = uigetfile({'*.wav';'*.mp3';'*.*'});  %open a audio file
            app.filepathLabel.Value = fullfile(path, file); 
            app.audioFile = strcat(path,file);
            try
                MAXN = 32768;
                [app.x, app.fs] = audioread(app.audioFile); %save to app variables
                
                while length(app.x) > MAXN
                    app.x = resample(app.x, app.fs-1000, app.fs);
                    app.fs = app.fs - 1000;
                end
                
                app.x = app.x';
                %to know the N-DFT options:
                app.n = length(app.x);
                leastN = 2^ceil(log2(app.n));
                nextN = 2 * leastN;
                DTF_options = [];
                DTF_options = [DTF_options leastN];
                while nextN < MAXN
                     DTF_options = [DTF_options nextN];
                     nextN = nextN * 2;
                end
                DTF_options = [DTF_options MAXN];
                items = cell(1,length(DTF_options));
                for i = 1:length(DTF_options)
                    items{i} = num2str(DTF_options(i));
                end
                app.NDFTDropDown.Items = items; %initialize the drop down menu of N-DFT
                app.NDFTDropDown_2.Items = items;
                app.NDFTDropDown_3.Items = items;
                app.WindLength.Value = app.n; %initialize the window length with minimum
                app.WindLength_2.Value = app.n;
                app.WindLength_3.Value = app.n;
                
              
                %initializing the options for segments in calculation mode
                app.numValidSplits = 0;
                i = 2;
                validSplitters = [];
                while i < app.n && app.numValidSplits < 5
                    if mod(app.n,i) == 0
                        validSplitters = [validSplitters i];
                        app.numValidSplits = app.numValidSplits + 1;
                    end
                    i = i + 1;
                end
                
                items = cell(1,app.numValidSplits);
                for i = 1:app.numValidSplits
                    items{i} = num2str(validSplitters(i));
                end
                app.NumSegMenu.Items = items;
                
                items = cell(1,app.numValidSplits);
                for i = 1:app.numValidSplits
                    items{i} = num2str(app.n/validSplitters(i));
                end
                app.LenSegMenu.Items = items;
            catch
%                 fig = uifigure;
%                 uialert(fig,'File not found','Invalid File');
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            try
                winChoice = app.WindType.Value;
                app.n = length(app.x);
                i = 1:app.n;
                rectWindow = @(L) i <= L;
                triWindow = @(L) ((2*i/L) .* (i <= L/2) + (2-2*i/L) .* (i > L/2)) .* (i <= L);
                hanWindow = @(L) (0.5 - 0.5 * cos(2*pi*i/L)) .* (i <= L);
                hamWindow = @(L) (0.54 - 0.46 * cos(2*pi*i/L)) .* (i <= L);
                windowLength = app.WindLength.Value;
                if winChoice == "Rectangular"
                    app.xWindowed = app.x .* rectWindow(windowLength);
                elseif winChoice == "Triangular"
                    app.xWindowed = app.x .* triWindow(windowLength);
                elseif winChoice == "Hanning"
                    app.xWindowed = app.x .* hanWindow(windowLength);
                elseif winChoice == "Hamming"
                    app.xWindowed = app.x .* hamWindow(windowLength);
                end
                lengthDFT = str2num(app.NDFTDropDown.Value);
                xPadded = [app.xWindowed zeros(1,lengthDFT-app.n)];
                
                [fourier,time] = fast_fourier(1,xPadded,'fft',app.fs);
                
                app.TimeDom.cla;
                app.FreqDom.cla;
                m = size(time,2);
                stem(app.TimeDom,0:m-1, (time));
                stem(app.FreqDom,linspace(-app.fs/2,app.fs/2-app.fs/m,m), abs(fftshift(fourier)));
            catch
                %error
            end
        end

        % Value changed function: NumSegMenu
        function NumSegMenuValueChanged(app, event)
            value = app.NumSegMenu.Value;
            numVal = str2num(value);
            app.LenSegMenu.Value = num2str(app.n/numVal);
        end

        % Value changed function: LenSegMenu
        function LenSegMenuValueChanged(app, event)
            value = app.LenSegMenu.Value;
            numVal = str2num(value);
            app.NumSegMenu.Value = num2str(app.n/numVal);
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            try
                %draw the RMS average
                numSplits = str2num(app.NumSegMenu.Value);
                X_avg = zeros(1,2^ceil(log2(app.n/numSplits)));
                for i = 1:numSplits
                    xSplit = app.x((i-1)*app.n/numSplits+1: i*app.n/numSplits);
                    [XSplit,~] = fast_fourier(1,xSplit,'fft',app.fs);
                    X_avg = X_avg + 1/numSplits * XSplit;
                end
                m = size(XSplit,2);
                stem(app.RMSgraph,linspace(-app.fs/2,app.fs/2-app.fs/m,m), abs(fftshift(X_avg)));
                
                %calculate power
                [fourier,~] = fast_fourier(1,app.x,'fft',app.fs);
                rangeStart = app.startFreq.Value;
                rangeEnd = app.EndFreq.Value;
                N = length(fourier);
                X_oneSided = fourier(1:N/2);
                PSD = 1/N^2 * (abs(X_oneSided) .^ 2);
                PSD(2:end) = 2*PSD(2:end);
                Power = sum(PSD(round(rangeStart * N/app.fs)+1 : round(rangeEnd * N/app.fs)+1));
                app.PowerDisplay.Value = Power;
            catch
                %error
            end
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)
            try
                %analyze _2 graphs
                winChoice = app.WindType_2.Value;
                app.n = length(app.x);
                i = 1:app.n;
                rectWindow = @(L) i <= L;
                triWindow = @(L) ((2*i/L) .* (i <= L/2) + (2-2*i/L) .* (i > L/2)) .* (i <= L);
                hanWindow = @(L) (0.5 - 0.5 * cos(2*pi*i/L)) .* (i <= L);
                hamWindow = @(L) (0.54 - 0.46 * cos(2*pi*i/L)) .* (i <= L);
                windowLength = app.WindLength_2.Value;
                if winChoice == "Rectangular"
                    app.xWindowed = app.x .* rectWindow(windowLength);
                elseif winChoice == "Triangular"
                    app.xWindowed = app.x .* triWindow(windowLength);
                elseif winChoice == "Hanning"
                    app.xWindowed = app.x .* hanWindow(windowLength);
                elseif winChoice == "Hamming"
                    app.xWindowed = app.x .* hamWindow(windowLength);
                end
                lengthDFT = str2num(app.NDFTDropDown_2.Value);
                xPadded = [app.xWindowed zeros(1,lengthDFT-app.n)];
                
                [fourier,time] = fast_fourier(1,xPadded,'fft',app.fs);
                
                app.TimeDom.cla;
                app.FreqDom.cla;
                m = size(time,2);
                stem(app.TimeDom_3,0:m-1, (time));
                stem(app.FreqDom_3,linspace(-app.fs/2,app.fs/2-app.fs/m,m), abs(fftshift(fourier)));
                
                %analyze _3 graphs
                winChoice = app.WindType_3.Value;
                app.n = length(app.x);
                i = 1:app.n;
                rectWindow = @(L) i <= L;
                triWindow = @(L) ((2*i/L) .* (i <= L/2) + (2-2*i/L) .* (i > L/2)) .* (i <= L);
                hanWindow = @(L) (0.5 - 0.5 * cos(2*pi*i/L)) .* (i <= L);
                hamWindow = @(L) (0.54 - 0.46 * cos(2*pi*i/L)) .* (i <= L);
                windowLength = app.WindLength_3.Value;
                if winChoice == "Rectangular"
                    app.xWindowed = app.x .* rectWindow(windowLength);
                elseif winChoice == "Triangular"
                    app.xWindowed = app.x .* triWindow(windowLength);
                elseif winChoice == "Hanning"
                    app.xWindowed = app.x .* hanWindow(windowLength);
                elseif winChoice == "Hamming"
                    app.xWindowed = app.x .* hamWindow(windowLength);
                end
                lengthDFT = str2num(app.NDFTDropDown_3.Value);
                xPadded = [app.xWindowed zeros(1,lengthDFT-app.n)];
                
                [fourier,time] = fast_fourier(1,xPadded,'fft',app.fs);
                
                app.TimeDom.cla;
                app.FreqDom.cla;
                m = size(time,2);
                stem(app.TimeDom_4,0:m-1, (time));
                stem(app.FreqDom_4,linspace(-app.fs/2,app.fs/2-app.fs/m,m), abs(fftshift(fourier)));
            catch
                %error
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UploadTab = uitab(app.TabGroup);
            app.UploadTab.Title = 'Upload';
            app.MATLABSPECTRUMANALYZERLabel = uilabel(app.UploadTab);
            app.MATLABSPECTRUMANALYZERLabel.Text = 'MATLAB SPECTRUM ANALYZER';
            app.Label = uilabel(app.UploadTab);
            app.Label.Text = {'This App works for audio files only. You can start by uploading the file in this tab, '; 'then you can:'; '1. Analyze your input with different windowing and DFT settings in "Analyze" tab.'; '2. Use RMS averaging method for different segment lengths, and calculate the power'; '    in  a certain span in the "Calculations" tab.'; '3. Compare the effect of different Windowing settings in the "Comparison" tab.'};
            app.Label_2 = uilabel(app.UploadTab);
            app.Label_2.Text = '----------------------------------------------------------------------------------------------------------------------------------';
            app.Label_3 = uilabel(app.UploadTab);
            app.Label_3.Text = '----------------------------------------------------------------------------------------------------------------------------------';
            app.UploadAudioButton = uibutton(app.UploadTab, 'push');
            app.UploadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @UploadAudioButtonPushed, true);
            app.UploadAudioButton.Text = 'Upload Audio';
            app.filepathLabel = uieditfield(app.UploadTab, 'text');
            app.AnalyzeTab = uitab(app.TabGroup);
            app.AnalyzeTab.Title = 'Analyze';
            app.TimeDom = uiaxes(app.AnalyzeTab);
            title(app.TimeDom, 'Time Domain')
            xlabel(app.TimeDom, 'time (n)')
            ylabel(app.TimeDom, 'Amplitude')
            app.FreqDom = uiaxes(app.AnalyzeTab);
            title(app.FreqDom, 'Frequency Domain')
            xlabel(app.FreqDom, 'Frequency')
            ylabel(app.FreqDom, 'Magnitude')
            app.WindowTypeDropDownLabel = uilabel(app.AnalyzeTab);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindType = uidropdown(app.AnalyzeTab);
            app.WindType.Items = {'Rectangular', 'Triangular', 'Hanning', 'Hamming'};
            app.WindType.Value = 'Rectangular';
            app.WindowSettingsLabel = uilabel(app.AnalyzeTab);
            app.WindowSettingsLabel.Text = 'Window Settings';
            app.NDFTDropDownLabel = uilabel(app.AnalyzeTab);
            app.NDFTDropDownLabel.Text = 'N-DFT';
            app.NDFTDropDown = uidropdown(app.AnalyzeTab);
            app.DFTSettingLabel = uilabel(app.AnalyzeTab);
            app.DFTSettingLabel.Text = 'DFT Setting';
            app.ApplyButton = uibutton(app.AnalyzeTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.WindowLengthEditFieldLabel_2 = uilabel(app.AnalyzeTab);
            app.WindowLengthEditFieldLabel_2.Text = 'Window Length';
            app.WindLength = uieditfield(app.AnalyzeTab, 'numeric');
            app.CalculationsTab = uitab(app.TabGroup);
            app.CalculationsTab.Title = 'Calculations';
            app.RMSgraph = uiaxes(app.CalculationsTab);
            title(app.RMSgraph, 'RMS Average - Frequency Domain')
            xlabel(app.RMSgraph, 'X')
            ylabel(app.RMSgraph, 'Y')
            app.StartfreqLabel = uilabel(app.CalculationsTab);
            app.StartfreqLabel.Text = 'Start freq. :';
            app.startFreq = uieditfield(app.CalculationsTab, 'numeric');
            app.EndfreqLabel = uilabel(app.CalculationsTab);
            app.EndfreqLabel.Text = 'End freq.  :';
            app.EndFreq = uieditfield(app.CalculationsTab, 'numeric');
            app.PowerEditFieldLabel = uilabel(app.CalculationsTab);
            app.PowerEditFieldLabel.Text = 'Power:';
            app.PowerDisplay = uieditfield(app.CalculationsTab, 'numeric');
            app.PowerDisplay.Editable = 'off';
            app.POWERCALCULATORLabel = uilabel(app.CalculationsTab);
            app.POWERCALCULATORLabel.Text = 'POWER CALCULATOR';
            app.NumberOfSegmentsDropDownLabel = uilabel(app.CalculationsTab);
            app.NumberOfSegmentsDropDownLabel.Text = {'Number'; 'Of'; 'Segments'};
            app.NumSegMenu = uidropdown(app.CalculationsTab);
            app.NumSegMenu.ValueChangedFcn = createCallbackFcn(app, @NumSegMenuValueChanged, true);
            app.LengthOfSegmentsDropDownLabel = uilabel(app.CalculationsTab);
            app.LengthOfSegmentsDropDownLabel.Text = {'Length'; 'Of'; 'Segments'};
            app.LenSegMenu = uidropdown(app.CalculationsTab);
            app.LenSegMenu.ValueChangedFcn = createCallbackFcn(app, @LenSegMenuValueChanged, true);
            app.DivisiontoSegmentsLabel = uilabel(app.CalculationsTab);
            app.DivisiontoSegmentsLabel.Text = 'Division to Segments';
            app.ApplyButton_2 = uibutton(app.CalculationsTab, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.ComparisonTab = uitab(app.TabGroup);
            app.ComparisonTab.Title = 'Comparison';
            app.TimeDom_3 = uiaxes(app.ComparisonTab);
            title(app.TimeDom_3, 'Time Domain')
            xlabel(app.TimeDom_3, 'time (n)')
            ylabel(app.TimeDom_3, 'Amplitude')
            app.FreqDom_3 = uiaxes(app.ComparisonTab);
            title(app.FreqDom_3, 'Frequency Domain')
            xlabel(app.FreqDom_3, 'Frequency')
            ylabel(app.FreqDom_3, 'Magnitude')
            app.TimeDom_4 = uiaxes(app.ComparisonTab);
            title(app.TimeDom_4, 'Time Domain')
            xlabel(app.TimeDom_4, 'time (n)')
            ylabel(app.TimeDom_4, 'Amplitude')
            app.FreqDom_4 = uiaxes(app.ComparisonTab);
            title(app.FreqDom_4, 'Frequency Domain')
            xlabel(app.FreqDom_4, 'Frequency')
            ylabel(app.FreqDom_4, 'Magnitude')
            app.WindowSettingsLabel_2 = uilabel(app.ComparisonTab);
            app.WindowSettingsLabel_2.Text = 'Window Settings';
            app.DFTSettingLabel_2 = uilabel(app.ComparisonTab);
            app.DFTSettingLabel_2.Text = 'DFT Setting';
            app.ApplyButton_3 = uibutton(app.ComparisonTab, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.WindowTypeDropDownLabel_2 = uilabel(app.ComparisonTab);
            app.WindowTypeDropDownLabel_2.Text = 'Window Type';
            app.WindType_2 = uidropdown(app.ComparisonTab);
            app.WindType_2.Items = {'Rectangular', 'Triangular', 'Hanning', 'Hamming'};
            app.WindType_2.Value = 'Rectangular';
            app.NDFTDropDown_2Label = uilabel(app.ComparisonTab);
            app.NDFTDropDown_2Label.Text = 'N-DFT';
            app.NDFTDropDown_2 = uidropdown(app.ComparisonTab);
            app.WindowLengthEditFieldLabel_3 = uilabel(app.ComparisonTab);
            app.WindowLengthEditFieldLabel_3.Text = 'Window Length';
            app.WindLength_2 = uieditfield(app.ComparisonTab, 'numeric');
            app.WindowSettingsLabel_3 = uilabel(app.ComparisonTab);
            app.WindowSettingsLabel_3.Text = 'Window Settings';
            app.WindowTypeDropDownLabel_3 = uilabel(app.ComparisonTab);
            app.WindowTypeDropDownLabel_3.Text = 'Window Type';
            app.WindType_3 = uidropdown(app.ComparisonTab);
            app.WindType_3.Items = {'Rectangular', 'Triangular', 'Hanning', 'Hamming'};
            app.WindType_3.Value = 'Rectangular';
            app.WindowLengthEditFieldLabel_4 = uilabel(app.ComparisonTab);
            app.WindowLengthEditFieldLabel_4.Text = 'Window Length';
            app.WindLength_3 = uieditfield(app.ComparisonTab, 'numeric');
            app.DFTSettingLabel_3 = uilabel(app.ComparisonTab);
            app.DFTSettingLabel_3.Text = 'DFT Setting';
            app.NDFTDropDown_3Label = uilabel(app.ComparisonTab);
            app.NDFTDropDown_3Label.Text = 'N-DFT';
            app.NDFTDropDown_3 = uidropdown(app.ComparisonTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectrumAnalyzerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end